classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ThresholdSlider       matlab.ui.control.Slider
        ThresholdSliderLabel  matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        TangentsButton        matlab.ui.control.Button
        widthasfuncButton     matlab.ui.control.Button
        LareaButton           matlab.ui.control.Button
        BinaryButton          matlab.ui.control.Button
        DrawhistogramButton   matlab.ui.control.Button
        GRAYIMAGEButton       matlab.ui.control.Button
        UPLOADButton          matlab.ui.control.Button
        UIAxes6               matlab.ui.control.UIAxes
        UIAxes5               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UPLOADButton
        function UPLOADButtonPushed(app, event)
            global a;
            
            [filename,pathname]=uigetfile('*.*','Pick the averaged image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            
            imshow(a,'Parent',app.UIAxes);


        end

        % Button pushed function: GRAYIMAGEButton
        function GRAYIMAGEButtonPushed(app, event)
            global a;
            global grayImage;
            global rows;
            fontSize = 10;
            grayImage = a;
            
            [rows, columns, numberOfColorBands] = size(grayImage);
            if numberOfColorBands > 1

            	grayImage = grayImage(:,:,2);
                
            end
            
            imshow(grayImage,'Parent',app.UIAxes2);


        end

        % Button pushed function: DrawhistogramButton
        function DrawhistogramButtonPushed(app, event)
            global grayImage;
            
           
            [pixelCount, grayLevels] = imhist(grayImage);

            pixelCount(1) = 0;
            pixelCount(end) = 0;
            
            bar(grayLevels, pixelCount,'Parent',app.UIAxes3);
            grid on;
            
            xlim([0 grayLevels(end)]);

        end

        % Button pushed function: BinaryButton
        function BinaryButtonPushed(app, event)
            global thresholdValue;
            global grayImage;
            global binaryImage;
            
            binaryImage = grayImage > thresholdValue;
            
            imshow(binaryImage,'Parent',app.UIAxes4);
        end

        % Button pushed function: LareaButton
        function LareaButtonPushed(app, event)
            global binaryImage;
            numberToExtract = 1;
            

                	[labeledImage, numberOfBlobs] = bwlabel(binaryImage);
                	blobMeasurements = regionprops(labeledImage, 'area');

                	allAreas = [blobMeasurements.Area];
                	if numberToExtract > length(allAreas);

                		numberToExtract = length(allAreas);
                	end
                	if numberToExtract > 0

                		[sortedAreas, sortIndexes] = sort(allAreas, 'descend');
                	elseif numberToExtract < 0
                		[sortedAreas, sortIndexes] = sort(allAreas, 'ascend');
                		numberToExtract = -numberToExtract;
                	else
                		binaryImage = false(size(binaryImage));
                		return;
                	end
                	biggestBlob = ismember(labeledImage, sortIndexes(1:numberToExtract));
                	binaryImage = biggestBlob > 0;


            %binaryImage = largestarea(binaryImage, numberToExtract);
            binaryImage = imfill(binaryImage, 'holes');

            se = strel('disk', 3, 0);
            binaryImage = imopen(binaryImage, se);


            imshow(binaryImage,'Parent',app.UIAxes5);


        end

        % Button pushed function: widthasfuncButton
        function widthasfuncButtonPushed(app, event)
            global binaryImage;
            global rows;
            global leftEdge;
            global rightEdge;
            widths = zeros(1, rows);
            leftEdge = zeros(1, rows);
            rightEdge = zeros(1, rows);
            for row = 1 : rows
            	thisRow = binaryImage(row, :);
            	leftIndex = find(thisRow, 1, 'first');
            	if ~isempty(leftIndex)
            		leftEdge(row) = leftIndex;
            		rightEdge(row) = find(thisRow, 1, 'last');
            		widths(row) = rightEdge(row) - leftEdge(row);
            	end
            end


            subplot(2, 6, 11);
            plot(1:rows, widths, 'b-', 'LineWidth', 2,'Parent',app.UIAxes6);
            grid on;
            
            axis on;

        end

        % Button pushed function: TangentsButton
        function TangentsButtonPushed(app, event)
            global leftEdge;
            global rightEdge;
            x = 90:240;

            y = leftEdge(x);
            leftCoefficients = polyfit(x, y, 1);
            leftAngle = atand(leftCoefficients(1))

            
            plot(x, y, 'b-', 'LineWidth', 2);
            hold on;
            y = rightEdge(x);
            rightCoefficients = polyfit(x, y, 1);
            rightAngle = atand(rightCoefficients(1))
            plot(x, y, 'r-', 'LineWidth', 2);
            grid on;
            coneAngle = abs(leftAngle) + abs(rightAngle);

            yLeftFit = polyval(leftCoefficients, x);
            plot(x, yLeftFit, 'b-', 'LineWidth', 2);
            yRightFit = polyval(rightCoefficients, x);
            plot(x, yRightFit, 'r-', 'LineWidth', 2);
            legend('left', 'right', 'Location', 'east');

            message = sprintf('The angle of cone is: %.1f degrees.', coneAngle);
            uiwait(helpdlg(message));
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            global thresholdValue;
            thresholdValue = app.ThresholdSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Average image ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gray image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Histogram')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Binary')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Largest area')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'width as function of line number')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.UPLOADButton = uibutton(app.ButtonGroup, 'push');
            app.UPLOADButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADButtonPushed, true);
            app.UPLOADButton.Text = 'UPLOAD';
            app.GRAYIMAGEButton = uibutton(app.ButtonGroup, 'push');
            app.GRAYIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @GRAYIMAGEButtonPushed, true);
            app.GRAYIMAGEButton.Text = 'GRAY IMAGE';
            app.DrawhistogramButton = uibutton(app.ButtonGroup, 'push');
            app.DrawhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @DrawhistogramButtonPushed, true);
            app.DrawhistogramButton.Text = 'Draw histogram';
            app.BinaryButton = uibutton(app.ButtonGroup, 'push');
            app.BinaryButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryButtonPushed, true);
            app.BinaryButton.Text = 'Binary';
            app.LareaButton = uibutton(app.ButtonGroup, 'push');
            app.LareaButton.ButtonPushedFcn = createCallbackFcn(app, @LareaButtonPushed, true);
            app.LareaButton.Text = 'L area';
            app.widthasfuncButton = uibutton(app.ButtonGroup, 'push');
            app.widthasfuncButton.ButtonPushedFcn = createCallbackFcn(app, @widthasfuncButtonPushed, true);
            app.widthasfuncButton.Text = 'width as func';
            app.TangentsButton = uibutton(app.ButtonGroup, 'push');
            app.TangentsButton.ButtonPushedFcn = createCallbackFcn(app, @TangentsButtonPushed, true);
            app.TangentsButton.Text = 'Tangents';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end